classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HistogramSpecificationPanel  matlab.ui.container.Panel
        ShowHistogramButton          matlab.ui.control.Button
        BrowseImageReferenceButton   matlab.ui.control.Button
        UIAxes_3                     matlab.ui.control.UIAxes
        PowerTransformationPanel     matlab.ui.container.Panel
        gammaEditField               matlab.ui.control.NumericEditField
        gammaEditFieldLabel          matlab.ui.control.Label
        cEditField_2                 matlab.ui.control.NumericEditField
        cEditField_2Label            matlab.ui.control.Label
        LogTransformationPanel       matlab.ui.container.Panel
        rEditField                   matlab.ui.control.NumericEditField
        rEditFieldLabel              matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        DoTheMagicButton             matlab.ui.control.Button
        MenuDropDown                 matlab.ui.control.DropDown
        MenuDropDownLabel            matlab.ui.control.Label
        ImageBrighteningPanel        matlab.ui.container.Panel
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        ShowHistogramOutputButton    matlab.ui.control.Button
        ShowHistogramInputButton     matlab.ui.control.Button
        BrowseImageButton            matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % Description
        isImageRGB % Description
        Output % Description
        ImageRef % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            global img;
            [filename,pathname] = uigetfile({'*'}, 'Open Input Image');
            fullpathname = strcat(pathname, filename);
            img = imread(fullpathname);
            app.Image = img;
            [x,y,z] = size(app.Image);
            app.isImageRGB = z == 3;
            imshow(img, 'parent', app.UIAxes);
        end

        % Button pushed function: ShowHistogramInputButton
        function ShowHistogramInputButtonPushed(app, event)
            if app.isImageRGB
                rgbHist(app.Image)
            else
                gsHist(app.Image)
            end
        end

        % Button pushed function: ShowHistogramOutputButton
        function ShowHistogramOutputButtonPushed(app, event)
            if app.isImageRGB
                rgbHist(app.Output)
            else
                gsHist(app.Output)
            end
        end

        % Value changed function: MenuDropDown
        function MenuDropDownValueChanged(app, event)
            value = app.MenuDropDown.Value;
            switch value
                case "Image Brightening"
                    app.ImageBrighteningPanel.Enable = true;
                    app.ImageBrighteningPanel.Visible = true;
                    app.LogTransformationPanel.Enable = false;
                    app.LogTransformationPanel.Visible = false;
                    app.PowerTransformationPanel.Enable = false;
                    app.PowerTransformationPanel.Visible = false;
                    app.HistogramSpecificationPanel.Enable = false;
                    app.HistogramSpecificationPanel.Visible = false;
                case "Log Transformation"
                    app.ImageBrighteningPanel.Enable = false;
                    app.ImageBrighteningPanel.Visible = false;
                    app.LogTransformationPanel.Enable = true;
                    app.LogTransformationPanel.Visible = true;
                    app.PowerTransformationPanel.Enable = false;
                    app.PowerTransformationPanel.Visible = false;
                    app.HistogramSpecificationPanel.Enable = false;
                    app.HistogramSpecificationPanel.Visible = false;
                case "Power Transformation"
                    app.ImageBrighteningPanel.Enable = false;
                    app.ImageBrighteningPanel.Visible = false;
                    app.LogTransformationPanel.Enable = false;
                    app.LogTransformationPanel.Visible = false;
                    app.PowerTransformationPanel.Enable = true;
                    app.PowerTransformationPanel.Visible = true;
                    app.HistogramSpecificationPanel.Enable = false;
                    app.HistogramSpecificationPanel.Visible = false;
                case "Histogram Specification"
                    app.ImageBrighteningPanel.Enable = false;
                    app.ImageBrighteningPanel.Visible = false;
                    app.LogTransformationPanel.Enable = false;
                    app.LogTransformationPanel.Visible = false;
                    app.PowerTransformationPanel.Enable = false;
                    app.PowerTransformationPanel.Visible = false;
                    app.HistogramSpecificationPanel.Enable = true;
                    app.HistogramSpecificationPanel.Visible = true;
                otherwise
                    app.ImageBrighteningPanel.Enable = false;
                    app.ImageBrighteningPanel.Visible = false;
                    app.LogTransformationPanel.Enable = false;
                    app.LogTransformationPanel.Visible = false;
                    app.PowerTransformationPanel.Enable = false;
                    app.PowerTransformationPanel.Visible = false;
                    app.HistogramSpecificationPanel.Enable = false;
                    app.HistogramSpecificationPanel.Visible = false;
            end
        end

        % Button pushed function: DoTheMagicButton
        function DoTheMagicButtonPushed(app, event)
            value = app.MenuDropDown.Value;
            switch value
                case "Image Brightening"
                    a = app.aEditField.Value;
                    b = app.bEditField.Value;
                    result = imageBrightening(app.Image,a,b);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                case "Negative Image"
                    result = negativeImage(app.Image);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                case "Log Transformation"
                    r = app.rEditField.Value;
                    c = app.cEditField.Value;
                    result = logTransform(app.Image,c,r);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                case "Power Transformation"
                    c = app.cEditField_2.Value;
                    g = app.gammaEditField.Value;
                    result = imageBrightening(app.Image,c,g);
                    app.Output = result;matlab:matlab.internal.language.introspective.errorDocCallback('app1/DoTheMagicButtonPushed', 'D:\FAHMI\KULIAH\SEMESTER 7\Interpretasi dan Pengolahan Citra\Tucil 1\IF4073-Tucil-1\src\script\app1.mlapp', 164)
                    imshow(result, 'parent', app.UIAxes_2);
                case "Contrast Stretch"
                    result = contrastStretching(app.Image);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                case "Histogram Equalization"
                    result = histEqualizer(app.Image);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                case "Histogram Specification"
                    result = histMatching(app.Image, app.ImageRef);
                    app.Output = result;
                    imshow(result, 'parent', app.UIAxes_2);
                otherwise
            end
        end

        % Button pushed function: BrowseImageReferenceButton
        function BrowseImageReferenceButtonPushed(app, event)
            global imgref;
            [filename,pathname] = uigetfile({'*'}, 'Open Reference Image');
            fullpathname = strcat(pathname, filename);
            imgref = imread(fullpathname);
            if(size(imgref) == size(app.Image))
                app.ImageRef = imgref;
                imshow(imgref, 'parent', app.UIAxes_3);
            else
                fig = uifigure;
                uialert(fig, "Ukuran Citra Acuan Berbeda", "Error")
            end
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            if app.isImageRGB
                rgbHist(app.ImageRef)
            else
                gsHist(app.ImageRef)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Result')
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.ShowHistogramInputButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramInputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramInputButtonPushed, true);
            app.ShowHistogramInputButton.Text = 'Show Histogram Input';
            app.ShowHistogramOutputButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramOutputButtonPushed, true);
            app.ShowHistogramOutputButton.Text = 'Show Histogram Output';
            app.ImageBrighteningPanel = uipanel(app.UIFigure);
            app.ImageBrighteningPanel.Title = 'Image Brightening';
            app.aEditFieldLabel = uilabel(app.ImageBrighteningPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ImageBrighteningPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.ImageBrighteningPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ImageBrighteningPanel, 'numeric');
            app.MenuDropDownLabel = uilabel(app.UIFigure);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.UIFigure);
            app.MenuDropDown.Items = {'Image Brightening', 'Negative Image', 'Log Transformation', 'Power Transformation', 'Contrast Stretch', 'Histogram Equalization', 'Histogram Specification'};
            app.MenuDropDown.ValueChangedFcn = createCallbackFcn(app, @MenuDropDownValueChanged, true);
            app.MenuDropDown.Value = 'Image Brightening';
            app.DoTheMagicButton = uibutton(app.UIFigure, 'push');
            app.DoTheMagicButton.ButtonPushedFcn = createCallbackFcn(app, @DoTheMagicButtonPushed, true);
            app.DoTheMagicButton.Text = 'Do The Magic!';
            app.LogTransformationPanel = uipanel(app.UIFigure);
            app.LogTransformationPanel.Enable = 'off';
            app.LogTransformationPanel.Title = 'Log Transformation';
            app.LogTransformationPanel.Visible = 'off';
            app.cEditFieldLabel = uilabel(app.LogTransformationPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.LogTransformationPanel, 'numeric');
            app.rEditFieldLabel = uilabel(app.LogTransformationPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.LogTransformationPanel, 'numeric');
            app.PowerTransformationPanel = uipanel(app.UIFigure);
            app.PowerTransformationPanel.Enable = 'off';
            app.PowerTransformationPanel.Title = 'Power Transformation';
            app.PowerTransformationPanel.Visible = 'off';
            app.cEditField_2Label = uilabel(app.PowerTransformationPanel);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.PowerTransformationPanel, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.PowerTransformationPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.PowerTransformationPanel, 'numeric');
            app.HistogramSpecificationPanel = uipanel(app.UIFigure);
            app.HistogramSpecificationPanel.Enable = 'off';
            app.HistogramSpecificationPanel.Title = 'Histogram Specification';
            app.HistogramSpecificationPanel.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.HistogramSpecificationPanel);
            title(app.UIAxes_3, 'Result')
            app.BrowseImageReferenceButton = uibutton(app.HistogramSpecificationPanel, 'push');
            app.BrowseImageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageReferenceButtonPushed, true);
            app.BrowseImageReferenceButton.Text = 'Browse Image Reference';
            app.ShowHistogramButton = uibutton(app.HistogramSpecificationPanel, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end